classdef RobotPackage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DHParametersTab                 matlab.ui.container.Tab
        NumberofLinks                   matlab.ui.control.NumericEditField
        NumberofLinksLabel              matlab.ui.control.Label
        UITable1                        matlab.ui.control.Table
        EnterButton_1                   matlab.ui.control.Button
        text1                           matlab.ui.control.Label
        DHParametersLabel               matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        WelcometoyourpersonalroboticsanalysisassitantLabel  matlab.ui.control.Label
        EnterButton_2                   matlab.ui.control.Button
        text3_3                         matlab.ui.control.Label
        LinkSpecificationsTab           matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        text1_2                         matlab.ui.control.Label
        LinkSpecificationsLabel         matlab.ui.control.Label
        EnterButton_3                   matlab.ui.control.Button
        text3_4                         matlab.ui.control.Label
        text1_3                         matlab.ui.control.Label
        LinkParametersTab               matlab.ui.container.Tab
        UITable4                        matlab.ui.control.Table
        text4                           matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        LinkParametersLabel             matlab.ui.control.Label
        EnterButton_4                   matlab.ui.control.Button
        text3_5                         matlab.ui.control.Label
        text3_6                         matlab.ui.control.Label
        DynamicsTab                     matlab.ui.container.Tab
        RobotDynamicsLabel              matlab.ui.control.Label
        EquationsofMotionTextAreaLabel  matlab.ui.control.Label
        EquationsofMotionTextArea       matlab.ui.control.TextArea
        text5                           matlab.ui.control.Label
        EnterButton_5                   matlab.ui.control.Button
        text6                           matlab.ui.control.Label
        UITable5                        matlab.ui.control.Table
        text5_2                         matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        EnterButton_6                   matlab.ui.control.Button
        DynamicSimulationLabel          matlab.ui.control.Label
        UITable6                        matlab.ui.control.Table
        SimulationTimeEditFieldLabel    matlab.ui.control.Label
        SimulationTimeEditField         matlab.ui.control.NumericEditField
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        ComplianceControlTab            matlab.ui.container.Tab
        ComplianceControlLabel          matlab.ui.control.Label
        UITable7                        matlab.ui.control.Table
        UITable9                        matlab.ui.control.Table
        UITable8                        matlab.ui.control.Table
        SimulationTimeEditField_2Label  matlab.ui.control.Label
        SimulationTimeEditField_2       matlab.ui.control.NumericEditField
        EnterButton_7                   matlab.ui.control.Button
        text5_3                         matlab.ui.control.Label
        text5_4                         matlab.ui.control.Label
        text5_5                         matlab.ui.control.Label
        text5_6                         matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        ImpedanceControlTab             matlab.ui.container.Tab
        ImpedanceControlLabel           matlab.ui.control.Label
        UITable10                       matlab.ui.control.Table
        UITable12                       matlab.ui.control.Table
        UITable11                       matlab.ui.control.Table
        EnterButton_8                   matlab.ui.control.Button
        SimulationTimeEditField_3Label  matlab.ui.control.Label
        SimulationTimeEditField_3       matlab.ui.control.NumericEditField
        UITable13                       matlab.ui.control.Table
        ksingleEditFieldLabel           matlab.ui.control.Label
        ksingleEditField                matlab.ui.control.NumericEditField
        text5_7                         matlab.ui.control.Label
        text5_8                         matlab.ui.control.Label
        text5_9                         matlab.ui.control.Label
        text5_10                        matlab.ui.control.Label
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Arm
        dh % Description
        Link_type % Description
        Linkspec
        Linkparam
        select        
        d
        a
        theta
        alpha
        I
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton_1
        function EnterButton_1Pushed(app, event)
            n = app.NumberofLinks.Value;
            assignin('base','n',n)

            z = zeros(n,1);
            x = repelem({'Revolute'},n)';
            c = categorical(x, {'Revolute', 'Prismatic'});
            app.UITable1.Data = table(z,z,z,z,c);
            app.UITable2.Data = table(z,z,z,z,z,z,z,z,z,c);
            app.UITable3.Data = table(z,z,z,z,z,z,z);
            app.UITable4.Data = table(z,z,z,z,z,z,z,z,z);
            app.UITable5.Data = table(zeros(3,1));
            app.UITable6.Data = table(z,z,z);
            app.UITable7.Data = table(zeros(6,1),zeros(6,1));
            app.UITable8.Data = table(z,z);
            app.UITable9.Data = table(0,0,0,0,0,0);
            app.UITable10.Data = table(zeros(6,1),zeros(6,1),zeros(6,1));
            app.UITable11.Data = table(z,z,z);
            app.UITable12.Data = table(0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0);
            app.UITable13.Data = table(0,0,0,0,0,0);
            
        end

        % Button pushed function: EnterButton_2
        function EnterButton_2Pushed(app, event)
            data = get(app.UITable1, 'Data');
            A = data.(1)(:,1);
            Alpha = data.(2)(:,1);
            D = data.(3)(:,1);
            Theta = data.(4)(:,1);
            app.Link_type = cellstr(data.(5)(:,1));
            app.dh = [A, Alpha, D, Theta];
            app.select = 1;
        end

        % Button pushed function: EnterButton_3
        function EnterButton_3Pushed(app, event)
            n = app.NumberofLinks.Value; 
            data = get(app.UITable2, 'Data');
            
            for i = 1:3
                Joint_direction(i,:) = data.(i)(:,1);
                Link_direction(i,:) = data.(i+3)(:,1);
                Joint_position(i,:) = data.(i+6)(:,1);
            end
            
            Joint_vector(:,1) = Joint_position(:,1);
            for i = 1:(n-1)
                Joint_vector(:,i+1) = Joint_position(:,i+1) - Joint_position(:,i);
            end
            
            for i = 1:n
                d_vector = dot(Joint_vector(:,i), Joint_direction(:,i));
                app.d(i) = norm(d_vector);
                a_vector = dot(Joint_vector(:,i), Link_direction(:,i));
                app.a(i) = norm(a_vector);
            end
            for i = 1:n-1
                app.theta(i) = atan2(Joint_direction(1,i)*Joint_direction(2,i+1),Joint_direction(1,i)*Joint_direction(1,i+1));
                app.alpha(i) = atan2(Joint_direction(2,i)*Joint_direction(3,i+1),Joint_direction(3,i)*Joint_direction(3,i+1));
            end   
            app.theta(n) = 0;
            app.alpha(n) = 0;
            
            app.Link_type = cellstr(data.(10)(:,1));
            app.select = 2;
        end

        % Button pushed function: EnterButton_4
        function EnterButton_4Pushed(app, event)
            n = app.NumberofLinks.Value;
            data = get(app.UITable3, 'Data');
            data2 = get(app.UITable4, 'Data');
            app.Linkparam = zeros(n,6);
            app.I = zeros(n,9);
            for i = 1:7
                app.Linkparam(:,i) = data.(i)(:,1);
            end
            for i = 1:9
                app.I(:,i) = data2.(i)(:,1);
            end
            
            if app.select == 1
                app.Arm = dhfunc(app.Link_type,app.dh(:,3),app.dh(:,1),app.dh(:,2),app.dh(:,4),app.Linkparam(:,1),app.I,app.Linkparam(:,2:4),app.Linkparam(:,6),app.Linkparam(:,5),app.Linkparam(:,7));
            end
            if app.select == 2
                app.Arm = dhfunc(app.Link_type,app.d,app.a,app.alpha,app.theta,app.Linkparam(:,1),app.I,app.Linkparam(:,2:4),app.Linkparam(:,6),app.Linkparam(:,5),app.Linkparam(:,7))
            end
            
        end

        % Button pushed function: EnterButton_5
        function EnterButton_5Pushed(app, event)
            n = app.NumberofLinks.Value;
            data = get(app.UITable5, 'Data');
            gravity = data.(1)(:,1);
            app.Arm.gravity = gravity;
            eq = eqsofmotion(app.Arm, n);
            %app.EquationsofMotionTextArea.Value = (char(eq));
            app.EquationsofMotionTextArea.Value = (eq);
            
            
        end

        % Button pushed function: EnterButton_6
        function EnterButton_6Pushed(app, event)
            data = get(app.UITable6, 'Data');
            torque = data.(1)(:,1);
            q0 = data.(2)(:,1);
            qd0 = data.(3)(:,1);
            time = app.SimulationTimeEditField.Value;
            [t, q, qd, qdd] = simdyn(app.Arm, time, q0, qd0, torque');
            plot(app.UIAxes1, t, q)
            plot(app.UIAxes2, t, qd)
            plot(app.UIAxes3, t, qdd)
            legend(app.UIAxes1,'q1','q2','q3','q4','q5','q6','q7','q8','q9','q10')
            legend(app.UIAxes2,'qd1','qd2','qd3','qd4','qd5','qd6','qd7','qd8','qd9','qd10')
            legend(app.UIAxes3,'qdd1','qdd2','qdd3','qdd4','qdd5','qdd6','qdd7','qdd8','qdd9','qdd10')
        end

        % Button pushed function: EnterButton_7
        function EnterButton_7Pushed(app, event)
            N = app.NumberofLinks.Value;
            data1 = get(app.UITable7, 'Data');
            data2 = get(app.UITable8, 'Data');
            data3 = get(app.UITable9, 'Data');
            
            kp = data1.(1)(:,1);
            kd = data1.(2)(:,1);
            Kp = diag(kp);
            Kd = diag(kd);
            
            qintial = data2.(1)(:,1);
            qdintial = data2.(2)(:,1);
            
            zangle = data3.(1)(:,1);
            yangle = data3.(2)(:,1);
            xangle = data3.(3)(:,1);
            x = data3.(4)(:,1);
            y = data3.(5)(:,1);
            z = data3.(6)(:,1);
            Rd = rotz(zangle)*roty(yangle)*rotx(xangle);
            t = [x; y; z];
            Od = [Rd, t;
                0, 0, 0, 1];
            
            Simt = app.SimulationTimeEditField_2.Value;            
            [heval,qplot,posval,t] = pdsim(app.Arm,Kp,Kd,qdintial,qintial,Od,Simt,N);           
            
            plot(app.UIAxes4, t, posval')
            plot(app.UIAxes5, t, heval)
            legend(app.UIAxes4, 'hx','hy','hz','hphi','htheta','hpsi')
            legend(app.UIAxes5, 'x', 'y', 'z')
            
            figure
            plot(t, qplot(1,:))
            hold on
            plot(t, qplot(2,:))
            legend('q1','q2')
            xlabel('time (s)')
            ylabel('angle (radians)')
            
        end

        % Button pushed function: EnterButton_8
        function EnterButton_8Pushed(app, event)
            N = app.NumberofLinks.Value;
            ksingle = app.ksingleEditField.Value;
            data1 = get(app.UITable10, 'Data');
            data2 = get(app.UITable11, 'Data');
            data3 = get(app.UITable12, 'Data');
            data4 = get(app.UITable13, 'Data');
            
            kp = data1.(1)(:,1);
            kd = data1.(2)(:,1);
            md = data1.(3)(:,1);
            Kp = diag(kp);
            Kd = diag(kd);
            Md = diag(md);
            
            qIntial = data2.(1)(:,1);
            dqIntial = data2.(2)(:,1);
            ddqIntial = data2.(3)(:,1);
            
            zangle = data3.(1)(:,1);
            yangle = data3.(2)(:,1);
            xangle = data3.(3)(:,1);
            dzangle = data3.(4)(:,1);
            dyangle = data3.(5)(:,1);
            dxangle = data3.(6)(:,1);
            ddzangle = data3.(7)(:,1);
            ddyangle = data3.(8)(:,1);
            ddxangle = data3.(9)(:,1);
            x = data3.(10)(:,1);
            y = data3.(11)(:,1);
            z = data3.(12)(:,1);
            dx = data3.(13)(:,1);
            dy = data3.(14)(:,1);
            dz = data3.(15)(:,1);
            ddx = data3.(16)(:,1);
            ddy = data3.(17)(:,1);
            ddz = data3.(18)(:,1);
            od = [x; y; z];
            dod = [dx; dy; dz];
            ddod = [ddx; ddy; ddz];
            Rd = rotz(zangle)*roty(yangle)*rotx(xangle);
            wd = [dxangle;dyangle;dzangle];
            dwd = [ddxangle;ddyangle;ddzangle];
            heIntial = zeros(6,1);
            
            for i = 1:6
                heIntial(i) = data4.(i)(:,1);
            end
            
            simt = app.SimulationTimeEditField_3.Value;            
            [heval,qval,posval,t] = impsim(app.Arm,Kp,Kd,Md,ksingle,dqIntial,ddqIntial,qIntial,heIntial,od,Rd,dod,wd,ddod,dwd,simt);

            plot(app.UIAxes6, t, posval)
            plot(app.UIAxes7, t, heval)
            legend(app.UIAxes4, 'hx','hy','hz','hphi','htheta','hpsi')
            legend(app.UIAxes5, 'x', 'y', 'z')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DHParametersTab = uitab(app.TabGroup);
            app.DHParametersTab.Title = 'DH Parameters';
            app.NumberofLinks = uieditfield(app.DHParametersTab, 'numeric');
            app.NumberofLinksLabel = uilabel(app.DHParametersTab);
            app.NumberofLinksLabel.Text = 'Number of Links';
            app.UITable1 = uitable(app.DHParametersTab);
            app.UITable1.ColumnName = {'A'; 'Alpha'; 'D'; 'Theta'; 'Link Type'};
            app.UITable1.RowName = {'Link 1'; 'Link 2'; 'Link 3'; 'Link 4'; 'Link 5'; 'Link 6'; 'Link 7'; 'LInk 8'; 'Link 9'; 'LInk 10'};
            app.UITable1.ColumnEditable = true;
            app.EnterButton_1 = uibutton(app.DHParametersTab, 'push');
            app.EnterButton_1.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_1Pushed, true);
            app.EnterButton_1.Text = 'Enter';
            app.text1 = uilabel(app.DHParametersTab);
            app.text1.Text = {'If you know the DH parameters for your robot fill out the following table and press enter.'; 'Otherwise continue to the Link Specifications page.'; 'Double click on Link type to access the drop down'};
            app.DHParametersLabel = uilabel(app.DHParametersTab);
            app.DHParametersLabel.Text = 'DH-Parameters';
            app.text3 = uilabel(app.DHParametersTab);
            app.text3.Text = 'Please fill out the number of links your robot contains and press enter';
            app.WelcometoyourpersonalroboticsanalysisassitantLabel = uilabel(app.DHParametersTab);
            app.WelcometoyourpersonalroboticsanalysisassitantLabel.Text = 'Welcome to your personal robotics analysis assitant!';
            app.EnterButton_2 = uibutton(app.DHParametersTab, 'push');
            app.EnterButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_2Pushed, true);
            app.EnterButton_2.Text = 'Enter';
            app.text3_3 = uilabel(app.DHParametersTab);
            app.text3_3.Text = 'If you filled out the DH-Parameters table and pressed enter, continue to the Link Parameters page.';
            app.LinkSpecificationsTab = uitab(app.TabGroup);
            app.LinkSpecificationsTab.Title = 'Link Specifications';
            app.UITable2 = uitable(app.LinkSpecificationsTab);
            app.UITable2.ColumnName = {'Joint Axis x-Direction'; 'Joint Axis y-Direction'; 'Joint Axis z-Direction'; 'Link x-Direction'; 'Link y-Direction'; 'Link z-Direction'; 'Link x-Position'; 'Link y-Position'; 'Link z-Position'; 'Link Type'};
            app.UITable2.RowName = {'Link 1'; 'Link 2'; 'Link 3'; 'Link 4'; 'Link 5'; 'Link 6'; 'Link 7'; 'LInk 8'; 'Link 9'; 'LInk 10'};
            app.UITable2.ColumnEditable = true;
            app.text1_2 = uilabel(app.LinkSpecificationsTab);
            app.text1_2.Text = {'If you did not fill out the DH-Parameters table then fill out the follwoing table when the robot is in the zero configuration.'; 'Otherwise continue to the Link Specifications page.'; 'Follow the directions below when filling out the table.'; 'Double click on Link type to access the drop down.'};
            app.LinkSpecificationsLabel = uilabel(app.LinkSpecificationsTab);
            app.LinkSpecificationsLabel.Text = 'Link Specifications';
            app.EnterButton_3 = uibutton(app.LinkSpecificationsTab, 'push');
            app.EnterButton_3.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_3Pushed, true);
            app.EnterButton_3.Text = 'Enter';
            app.text3_4 = uilabel(app.LinkSpecificationsTab);
            app.text3_4.Text = 'Continue to the Link Parameters Tab';
            app.text1_3 = uilabel(app.LinkSpecificationsTab);
            app.text1_3.Text = {'1. Place your base frame on the first joint witht the z-axis aligned with the axis of rotation.'; '2. Measure the direction of each joint axis relative to the base frame.'; '3. Measure the direction of the link from the joint to the next joint relative to the base frame.'; '4. Measure the position of the the following joint relative to the base frame. Ending with the end-effector position'};
            app.LinkParametersTab = uitab(app.TabGroup);
            app.LinkParametersTab.Title = 'Link Parameters';
            app.UITable4 = uitable(app.LinkParametersTab);
            app.UITable4.ColumnName = {'Ixx'; 'Ixy'; 'Ixz'; 'Iyx'; 'Iyy'; 'Iyz'; 'Izx'; 'Izy'; 'Izz'};
            app.UITable4.RowName = {'Link 1'; 'Link 2'; 'Link 3'; 'Link 4'; 'Link 5'; 'Link 6'; 'Link 7'; 'LInk 8'; 'Link 9'; 'LInk 10'};
            app.UITable4.ColumnEditable = true;
            app.text4 = uilabel(app.LinkParametersTab);
            app.text4.Text = {'Please fill out the following information needed to compute the equations of motion.'; 'Rx, Ry, Rz - Center of mass; Im - Moment of inertia for the motor; Kr - Gearing Ratio Fv - Viscous Friction'; 'Press Enter when finished'};
            app.UITable3 = uitable(app.LinkParametersTab);
            app.UITable3.ColumnName = {'Mass'; 'Rx'; 'Ry'; 'Rz'; 'Im'; 'Kr'; 'Fv'};
            app.UITable3.RowName = {'Link 1'; 'Link 2'; 'Link 3'; 'Link 4'; 'Link 5'; 'Link 6'; 'Link 7'; 'LInk 8'; 'Link 9'; 'LInk 10'};
            app.UITable3.ColumnEditable = true;
            app.LinkParametersLabel = uilabel(app.LinkParametersTab);
            app.LinkParametersLabel.Text = 'Link Parameters';
            app.EnterButton_4 = uibutton(app.LinkParametersTab, 'push');
            app.EnterButton_4.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_4Pushed, true);
            app.EnterButton_4.Text = 'Enter';
            app.text3_5 = uilabel(app.LinkParametersTab);
            app.text3_5.Text = 'Moment of inertia of the links';
            app.text3_6 = uilabel(app.LinkParametersTab);
            app.text3_6.Text = 'If the robot appears correct, continue on to the Dynamics tab. Otherwise verify your inputs.';
            app.DynamicsTab = uitab(app.TabGroup);
            app.DynamicsTab.Title = 'Dynamics';
            app.RobotDynamicsLabel = uilabel(app.DynamicsTab);
            app.RobotDynamicsLabel.Text = 'Robot Dynamics';
            app.EquationsofMotionTextAreaLabel = uilabel(app.DynamicsTab);
            app.EquationsofMotionTextAreaLabel.Text = 'Equations of Motion';
            app.EquationsofMotionTextArea = uitextarea(app.DynamicsTab);
            app.text5 = uilabel(app.DynamicsTab);
            app.text5.Text = 'The following information is needed to compute the equations of motion';
            app.EnterButton_5 = uibutton(app.DynamicsTab, 'push');
            app.EnterButton_5.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_5Pushed, true);
            app.EnterButton_5.Text = 'Enter';
            app.text6 = uilabel(app.DynamicsTab);
            app.text6.Text = 'After reviewing the equations of motion, continue to the Simulation or Control tabs ';
            app.UITable5 = uitable(app.DynamicsTab);
            app.UITable5.ColumnName = {'Gravity Direction'};
            app.UITable5.RowName = {'x'; 'y'; 'z'};
            app.UITable5.ColumnEditable = true;
            app.text5_2 = uilabel(app.DynamicsTab);
            app.text5_2.Text = 'After pressing enter, the equations of motion will appear in the following block. This may take a few minutes.';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.EnterButton_6 = uibutton(app.SimulationTab, 'push');
            app.EnterButton_6.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_6Pushed, true);
            app.EnterButton_6.Text = 'Enter';
            app.DynamicSimulationLabel = uilabel(app.SimulationTab);
            app.DynamicSimulationLabel.Text = 'Dynamic Simulation';
            app.UITable6 = uitable(app.SimulationTab);
            app.UITable6.ColumnName = {'Torque Input'; 'q initial'; 'qdot initial'};
            app.UITable6.RowName = {'Link 1'; 'Link 2'; 'Link 3'; 'Link 4'; 'Link 5'; 'Link 6'; 'Link 7'; 'LInk 8'; 'Link 9'; 'LInk 10'};
            app.UITable6.ColumnEditable = true;
            app.SimulationTimeEditFieldLabel = uilabel(app.SimulationTab);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.SimulationTab, 'numeric');
            app.UIAxes3 = uiaxes(app.SimulationTab);
            title(app.UIAxes3, 'Joint Acclerations')
            xlabel(app.UIAxes3, 'Time(s)')
            ylabel(app.UIAxes3, 'Angular Acceleration(rad/s^2)')
            app.UIAxes2 = uiaxes(app.SimulationTab);
            title(app.UIAxes2, 'Joint Velocities')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Angular Velocity(rad/s)')
            app.UIAxes1 = uiaxes(app.SimulationTab);
            title(app.UIAxes1, 'Joint Angles')
            xlabel(app.UIAxes1, 'Time(s)')
            ylabel(app.UIAxes1, 'Angle(rad)')
            app.ComplianceControlTab = uitab(app.TabGroup);
            app.ComplianceControlTab.Title = 'Compliance Control';
            app.ComplianceControlLabel = uilabel(app.ComplianceControlTab);
            app.ComplianceControlLabel.Text = 'Compliance Control';
            app.UITable7 = uitable(app.ComplianceControlTab);
            app.UITable7.ColumnName = {'Kp'; 'Kd'};
            app.UITable7.RowName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable7.ColumnEditable = true;
            app.UITable9 = uitable(app.ComplianceControlTab);
            app.UITable9.ColumnName = {'Z-Rotation'; 'Y-Rotation'; 'X-Rotation'; 'X-Position'; 'Y-Position'; 'Z-Position'};
            app.UITable9.RowName = {};
            app.UITable9.ColumnEditable = true;
            app.UITable8 = uitable(app.ComplianceControlTab);
            app.UITable8.ColumnName = {'q initial'; 'qdot initial'};
            app.UITable8.RowName = {'Link 1'; 'Link 2'; 'Link 3'; 'Link 4'; 'Link 5'; 'Link 6'; 'Link 7'; 'LInk 8'; 'Link 9'; 'LInk 10'};
            app.UITable8.ColumnEditable = true;
            app.SimulationTimeEditField_2Label = uilabel(app.ComplianceControlTab);
            app.SimulationTimeEditField_2Label.Text = 'Simulation Time';
            app.SimulationTimeEditField_2 = uieditfield(app.ComplianceControlTab, 'numeric');
            app.EnterButton_7 = uibutton(app.ComplianceControlTab, 'push');
            app.EnterButton_7.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_7Pushed, true);
            app.EnterButton_7.Text = 'Enter';
            app.text5_3 = uilabel(app.ComplianceControlTab);
            app.text5_3.Text = 'Controller Gains';
            app.text5_4 = uilabel(app.ComplianceControlTab);
            app.text5_4.Text = 'Inititial Joint Conditions';
            app.text5_5 = uilabel(app.ComplianceControlTab);
            app.text5_5.Text = 'Desired Joint Conditions';
            app.text5_6 = uilabel(app.ComplianceControlTab);
            app.text5_6.Text = {'After filling out the information shown press enter. '; 'A 3D simulation fo the robot configuration will apear and plots will be created. '; 'This may take a few minutes.'};
            app.UIAxes4 = uiaxes(app.ComplianceControlTab);
            title(app.UIAxes4, 'Contact Forces')
            xlabel(app.UIAxes4, 'Time(s)')
            ylabel(app.UIAxes4, 'Force(N)')
            app.UIAxes5 = uiaxes(app.ComplianceControlTab);
            title(app.UIAxes5, 'End Effector Position')
            xlabel(app.UIAxes5, 'Time(s)')
            ylabel(app.UIAxes5, 'Position(m)')
            app.ImpedanceControlTab = uitab(app.TabGroup);
            app.ImpedanceControlTab.Title = 'Impedance Control';
            app.ImpedanceControlLabel = uilabel(app.ImpedanceControlTab);
            app.ImpedanceControlLabel.Text = 'Impedance Control';
            app.UITable10 = uitable(app.ImpedanceControlTab);
            app.UITable10.ColumnName = {'Kp'; 'Kd'; 'Md'};
            app.UITable10.RowName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable10.ColumnEditable = true;
            app.UITable12 = uitable(app.ImpedanceControlTab);
            app.UITable12.ColumnName = {'Roll'; 'Pitch'; 'Yaw'; 'Roll_dot'; 'Pitch_dot'; 'Yaw_dot'; 'Roll_ddot'; 'Pitch_ddot'; 'Yaw_dot'; 'X-Position'; 'Y-Position'; 'Z-Position'; 'xdot'; 'ydot'; 'zdot'; 'xddot'; 'yddot'; 'zddot'};
            app.UITable12.RowName = {};
            app.UITable12.ColumnEditable = [true true true true true true true true true true true true false false false false false false];
            app.UITable11 = uitable(app.ImpedanceControlTab);
            app.UITable11.ColumnName = {'q initial'; 'qdot initial'; 'qddot initial'};
            app.UITable11.RowName = {'Link 1'; 'Link 2'; 'Link 3'; 'Link 4'; 'Link 5'; 'Link 6'; 'Link 7'; 'LInk 8'; 'Link 9'; 'LInk 10'};
            app.UITable11.ColumnEditable = true;
            app.EnterButton_8 = uibutton(app.ImpedanceControlTab, 'push');
            app.EnterButton_8.ButtonPushedFcn = createCallbackFcn(app, @EnterButton_8Pushed, true);
            app.EnterButton_8.Text = 'Enter';
            app.SimulationTimeEditField_3Label = uilabel(app.ImpedanceControlTab);
            app.SimulationTimeEditField_3Label.Text = 'Simulation Time';
            app.SimulationTimeEditField_3 = uieditfield(app.ImpedanceControlTab, 'numeric');
            app.UITable13 = uitable(app.ImpedanceControlTab);
            app.UITable13.ColumnName = {'hex'; 'hey'; 'hez'; 'he_roll'; 'he_pitch'; 'he_yaw'};
            app.UITable13.RowName = {};
            app.UITable13.ColumnEditable = true;
            app.ksingleEditFieldLabel = uilabel(app.ImpedanceControlTab);
            app.ksingleEditFieldLabel.Text = 'ksingle';
            app.ksingleEditField = uieditfield(app.ImpedanceControlTab, 'numeric');
            app.text5_7 = uilabel(app.ImpedanceControlTab);
            app.text5_7.Text = 'Controller Gains';
            app.text5_8 = uilabel(app.ImpedanceControlTab);
            app.text5_8.Text = 'Inititial Joint Conditions';
            app.text5_9 = uilabel(app.ImpedanceControlTab);
            app.text5_9.Text = 'Desired Joint Conditions';
            app.text5_10 = uilabel(app.ImpedanceControlTab);
            app.text5_10.Text = {'After filling out the information shown press enter. Ksingle is used to avoid singularities.'; 'A 3D simulation fo the robot configuration will apear and plots will be created. This may take a few minutes.'};
            app.UIAxes6 = uiaxes(app.ImpedanceControlTab);
            title(app.UIAxes6, 'End Effector Postion')
            xlabel(app.UIAxes6, 'Time(s)')
            ylabel(app.UIAxes6, 'Position(m)')
            app.UIAxes7 = uiaxes(app.ImpedanceControlTab);
            title(app.UIAxes7, 'Contact Forces')
            xlabel(app.UIAxes7, 'Time(s)')
            ylabel(app.UIAxes7, 'Force(N)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotPackage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end